classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Label_5                     matlab.ui.control.Label
        ButtonPause                 matlab.ui.control.Button
        ButtonOut                   matlab.ui.control.Button
        ButtonStop                  matlab.ui.control.Button
        ButtonStart                 matlab.ui.control.Button
        TdEditField                 matlab.ui.control.NumericEditField
        TdEditFieldLabel            matlab.ui.control.Label
        TiEditField                 matlab.ui.control.NumericEditField
        TiEditFieldLabel            matlab.ui.control.Label
        KcEditField_2               matlab.ui.control.NumericEditField
        KcEditField_2Label          matlab.ui.control.Label
        SetPointSlider              matlab.ui.control.Slider
        SetPointSliderLabel         matlab.ui.control.Label
        LevelTankGauge              matlab.ui.control.LinearGauge
        LevelTankGaugeLabel         matlab.ui.control.Label
        BombaOutSwitch              matlab.ui.control.RockerSwitch
        BombaOutLabel               matlab.ui.control.Label
        BombaInSwitch               matlab.ui.control.RockerSwitch
        BombaInSwitchLabel          matlab.ui.control.Label
        LampBombaOut                matlab.ui.control.Lamp
        LampBombaIn                 matlab.ui.control.Lamp
        MododeOperacionButtonGroup  matlab.ui.container.ButtonGroup
        ManualButton                matlab.ui.control.RadioButton
        AutoButton                  matlab.ui.control.RadioButton
        ConstantesPIDLabel          matlab.ui.control.Label
        Gauge                       matlab.ui.control.Gauge
        GaugeLabel                  matlab.ui.control.Label
        Gauge_2                     matlab.ui.control.Gauge
        Gauge_2Label                matlab.ui.control.Label
        LevelTank                   matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label2                      matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DataPort % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataPort = serialport("COM6", 9600);
            configureTerminator(app.DataPort,"CR/LF");
            %fopen(app.DataPort);
            app.LampBombaIn.Color = 'red';
            app.LampBombaOut.Color = 'red';
        end

        % Button pushed function: ButtonStart
        function ButtonStartPushed(app, event)
            %bucle principal
            global stop pauseFlag SetPoint
            stop = 0;
            SetPoint = 51;
            pauseFlag = false;
            i=1;
            max_samples = 100;
            SetPointValues = [];
            while(1)
                if stop == 1
                    break;
                elseif pauseFlag == true
                    pause(0.1);
                    continue;
                else
                    %Configuraciones base
                    app.LampBombaIn.Color = 'green';
                    app.LampBombaOut.Color = 'green';
                    
                    set(app.Label_5, 'Text',num2str(SetPoint));

                    Sample(i) = i;
                    Date(i) = str2double(readline(app.DataPort));
                    SetPointValues(i) = SetPoint;

                    plot(app.UIAxes, Sample(max(1, i-max_samples+1):i), Date(max(1, i-max_samples+1):i), ...
                        Sample(1:i), SetPointValues(1:i), 'r--');      
                    ylim(app.UIAxes, [0, 200]); % Límites del eje Y
                    xlim(app.UIAxes, [max(0, i-max_samples), i]); % Desplazar el eje X

                    i = i+1;
                    fprintf(app.DataPort, '%d\n', SetPoint);
                    pause(0.1);
                end
            end
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            global SetPoint stop
            SetPoint = 51;
            stop = 1;
            app.LampBombaIn.Color = 'red';
            app.LampBombaOut.Color = 'red';
        end

        % Value changing function: SetPointSlider
        function SetPointSliderValueChanging(app, event)
            global SetPoint
            SetPoint = round(event.Value); 
        end

        % Button pushed function: ButtonOut
        function ButtonOutPushed(app, event)
            app.delete;
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            global pauseFlag
            pauseFlag = ~pauseFlag;
            app.LampBombaIn.Color = 'blue';
            app.LampBombaOut.Color = 'blue';
            if pauseFlag
                app.ButtonPause.BackgroundColor = 'blue';
            else
                app.ButtonPause.BackgroundColor = [0.96, 0.96, 0.96];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.LevelTank = uilabel(app.UIFigure);
            app.LevelTank.Text = '';
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = '';
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = '';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.ConstantesPIDLabel = uilabel(app.UIFigure);
            app.ConstantesPIDLabel.Text = 'Constantes PID';
            app.MododeOperacionButtonGroup = uibuttongroup(app.UIFigure);
            app.MododeOperacionButtonGroup.Title = 'Modo de Operacion';
            app.AutoButton = uiradiobutton(app.MododeOperacionButtonGroup);
            app.AutoButton.Text = 'Auto';
            app.AutoButton.Value = true;
            app.ManualButton = uiradiobutton(app.MododeOperacionButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.LampBombaIn = uilamp(app.UIFigure);
            app.LampBombaOut = uilamp(app.UIFigure);
            app.BombaInSwitchLabel = uilabel(app.UIFigure);
            app.BombaInSwitchLabel.Text = 'Bomba In';
            app.BombaInSwitch = uiswitch(app.UIFigure, 'rocker');
            app.BombaOutLabel = uilabel(app.UIFigure);
            app.BombaOutLabel.Text = 'Bomba Out';
            app.BombaOutSwitch = uiswitch(app.UIFigure, 'rocker');
            app.LevelTankGaugeLabel = uilabel(app.UIFigure);
            app.LevelTankGaugeLabel.Text = 'Level Tank';
            app.LevelTankGauge = uigauge(app.UIFigure, 'linear');
            app.SetPointSliderLabel = uilabel(app.UIFigure);
            app.SetPointSliderLabel.Text = 'Set Point';
            app.SetPointSlider = uislider(app.UIFigure);
            app.SetPointSlider.ValueChangingFcn = createCallbackFcn(app, @SetPointSliderValueChanging, true);
            app.SetPointSlider.Value = 60;
            app.KcEditField_2Label = uilabel(app.UIFigure);
            app.KcEditField_2Label.Text = 'Kc';
            app.KcEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TiEditFieldLabel = uilabel(app.UIFigure);
            app.TiEditFieldLabel.Text = 'Ti';
            app.TiEditField = uieditfield(app.UIFigure, 'numeric');
            app.TdEditFieldLabel = uilabel(app.UIFigure);
            app.TdEditFieldLabel.Text = 'Td';
            app.TdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ButtonStart = uibutton(app.UIFigure, 'push');
            app.ButtonStart.ButtonPushedFcn = createCallbackFcn(app, @ButtonStartPushed, true);
            app.ButtonStart.Icon = fullfile(pathToMLAPP, 'boton-de-play.png');
            app.ButtonStart.Text = '';
            app.ButtonStop = uibutton(app.UIFigure, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Icon = fullfile(pathToMLAPP, 'detener.png');
            app.ButtonStop.Text = '';
            app.ButtonOut = uibutton(app.UIFigure, 'push');
            app.ButtonOut.ButtonPushedFcn = createCallbackFcn(app, @ButtonOutPushed, true);
            app.ButtonOut.Icon = fullfile(pathToMLAPP, 'cerrar-sesion.png');
            app.ButtonOut.Text = '';
            app.ButtonPause = uibutton(app.UIFigure, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Icon = fullfile(pathToMLAPP, 'pausa.png');
            app.ButtonPause.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end